classdef CFD_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        DisplayOptionsWIPLabel        matlab.ui.control.Label
        Tree                          matlab.ui.container.CheckBoxTree
        ParamstoDisplayNode           matlab.ui.container.TreeNode
        DensityNode                   matlab.ui.container.TreeNode
        XVelocityNode                 matlab.ui.container.TreeNode
        YVelocityNode                 matlab.ui.container.TreeNode
        ENode                         matlab.ui.container.TreeNode
        ArtificialViscEditField       matlab.ui.control.NumericEditField
        ArtificialViscEditFieldLabel  matlab.ui.control.Label
        EndTimesEditField             matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel        matlab.ui.control.Label
        BoundaryDropDown              matlab.ui.control.DropDown
        BoundaryDropDownLabel         matlab.ui.control.Label
        SolverParametersLabel         matlab.ui.control.Label
        CFLEditField                  matlab.ui.control.NumericEditField
        CFLEditFieldLabel             matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        SimulationTerminatedLabel     matlab.ui.control.Label
        FlowVisualiser                matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        IterationNumberField          matlab.ui.control.NumericEditField
        IterationLabel                matlab.ui.control.Label
        CoordinatesofLeadingEdgeY     matlab.ui.control.NumericEditField
        CoordinatesofLeadingEdgeX     matlab.ui.control.NumericEditField
        PositionofLeadingEdgexyLabel  matlab.ui.control.Label
        NACA4DigitCodeEditField       matlab.ui.control.EditField
        NACA4DigitCodeEditFieldLabel  matlab.ui.control.Label
        AngleofAttackEditField        matlab.ui.control.NumericEditField
        AngleofAttackEditFieldLabel   matlab.ui.control.Label
        ChordLengthEditField          matlab.ui.control.NumericEditField
        ChordLengthEditFieldLabel     matlab.ui.control.Label
        YGridEditField                matlab.ui.control.NumericEditField
        YDomainEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        XGridEditField                matlab.ui.control.NumericEditField
        XDomainEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        NumberofPointsEditField       matlab.ui.control.NumericEditField
        ChordmLabel                   matlab.ui.control.Label
        GridSizecellsLabel            matlab.ui.control.Label
        degLabel                      matlab.ui.control.Label
        NumberofPointsEditFieldLabel  matlab.ui.control.Label
        DomainSizeLabel               matlab.ui.control.Label
        DefineMeshLabel               matlab.ui.control.Label
        DefineAerofoilLabel           matlab.ui.control.Label
        SimulationTimeField           matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel  matlab.ui.control.Label
        TerminateSimulationButton     matlab.ui.control.Button
        PauseSimulationButton         matlab.ui.control.StateButton
        NewSimulationButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        TerminateApp % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NewSimulationButton
        function NewSimulationButtonPushed(app, event)
            if isempty(app.NACA4DigitCodeEditField.Value)
                uialert(app.UIFigure, "You must select a NACA aerofoil code.", "Input Required!")
                return
            end
            app.NewSimulationButton.Enable = "off";
            app.FlowVisualiser.Visible = "on";
            app.FlowVisualiser.Toolbar.Visible = "on";
            app.SimulationTerminatedLabel.Visible = "off";
            app.TerminateApp = false;
            try
                GPT(app)
            catch me
                uialert(app.UIFigure, getReport(me), "Error!","Interpreter","html")
            end
            app.NewSimulationButton.Enable = "on";
        end

        % Button pushed function: TerminateSimulationButton
        function StopIterationButtonPushed(app, event)
            app.TerminateApp = true;
            app.SimulationTerminatedLabel.Visible = "on";
            app.NewSimulationButton.Enable = "on";
        end

        % Value changed function: PauseSimulationButton
        function PauseSimulationButtonValueChanged(app, event)
            if app.PauseSimulationButton.Value
                app.PauseSimulationButton.Text = 'Resume Simulation';
            else
                app.PauseSimulationButton.Text = 'Pause Simulation';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {602, 602, 602};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {602, 602};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {165, '1x', 252};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CFLEditFieldLabel = uilabel(app.LeftPanel);
            app.CFLEditFieldLabel.Text = 'CFL';
            app.CFLEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CFLEditField.Limits = [0 1];
            app.CFLEditField.Value = 0.2;
            app.SolverParametersLabel = uilabel(app.LeftPanel);
            app.SolverParametersLabel.Text = 'Solver Parameters';
            app.BoundaryDropDownLabel = uilabel(app.LeftPanel);
            app.BoundaryDropDownLabel.Text = 'Boundary';
            app.BoundaryDropDown = uidropdown(app.LeftPanel);
            app.BoundaryDropDown.Items = {'outflow', 'periodic', 'wall'};
            app.BoundaryDropDown.Value = 'outflow';
            app.EndTimesEditFieldLabel = uilabel(app.LeftPanel);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EndTimesEditField.Limits = [0 Inf];
            app.EndTimesEditField.Value = 5;
            app.ArtificialViscEditFieldLabel = uilabel(app.LeftPanel);
            app.ArtificialViscEditFieldLabel.Text = 'Artificial Visc.';
            app.ArtificialViscEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ArtificialViscEditField.Limits = [0 1];
            app.ArtificialViscEditField.Value = 0.01;
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.ParamstoDisplayNode = uitreenode(app.Tree);
            app.ParamstoDisplayNode.Text = 'Params to Display';
            app.DensityNode = uitreenode(app.ParamstoDisplayNode);
            app.DensityNode.Text = 'Density';
            app.XVelocityNode = uitreenode(app.ParamstoDisplayNode);
            app.XVelocityNode.Text = 'X Velocity';
            app.YVelocityNode = uitreenode(app.ParamstoDisplayNode);
            app.YVelocityNode.Text = 'Y Velocity';
            app.ENode = uitreenode(app.ParamstoDisplayNode);
            app.ENode.Text = 'E';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.DensityNode];
            app.DisplayOptionsWIPLabel = uilabel(app.LeftPanel);
            app.DisplayOptionsWIPLabel.Text = 'Display Options (WIP)';
            app.CenterPanel = uipanel(app.GridLayout);
            app.FlowVisualiser = uiaxes(app.CenterPanel);
            title(app.FlowVisualiser, 'Flow')
            xlabel(app.FlowVisualiser, 'X')
            ylabel(app.FlowVisualiser, 'Y')
            zlabel(app.FlowVisualiser, 'Z')
            app.FlowVisualiser.Visible = 'off';
            app.SimulationTerminatedLabel = uilabel(app.CenterPanel);
            app.SimulationTerminatedLabel.Visible = 'off';
            app.SimulationTerminatedLabel.Text = 'Simulation Terminated';
            app.RightPanel = uipanel(app.GridLayout);
            app.NewSimulationButton = uibutton(app.RightPanel, 'push');
            app.NewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NewSimulationButtonPushed, true);
            app.NewSimulationButton.Text = 'New Simulation';
            app.PauseSimulationButton = uibutton(app.RightPanel, 'state');
            app.PauseSimulationButton.ValueChangedFcn = createCallbackFcn(app, @PauseSimulationButtonValueChanged, true);
            app.PauseSimulationButton.Text = 'Pause Simulation';
            app.TerminateSimulationButton = uibutton(app.RightPanel, 'push');
            app.TerminateSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopIterationButtonPushed, true);
            app.TerminateSimulationButton.Text = 'Terminate Simulation';
            app.SimulationTimeEditFieldLabel = uilabel(app.RightPanel);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeField = uieditfield(app.RightPanel, 'numeric');
            app.SimulationTimeField.Limits = [0 Inf];
            app.SimulationTimeField.Editable = 'off';
            app.DefineAerofoilLabel = uilabel(app.RightPanel);
            app.DefineAerofoilLabel.Text = 'Define Aerofoil';
            app.DefineMeshLabel = uilabel(app.RightPanel);
            app.DefineMeshLabel.Text = 'Define Mesh';
            app.DomainSizeLabel = uilabel(app.RightPanel);
            app.DomainSizeLabel.Text = 'Domain Size (m)';
            app.NumberofPointsEditFieldLabel = uilabel(app.RightPanel);
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.degLabel = uilabel(app.RightPanel);
            app.degLabel.Text = '(°)';
            app.GridSizecellsLabel = uilabel(app.RightPanel);
            app.GridSizecellsLabel.Text = 'Grid Size (cells)';
            app.ChordmLabel = uilabel(app.RightPanel);
            app.ChordmLabel.Text = '(m)';
            app.NumberofPointsEditField = uieditfield(app.RightPanel, 'numeric');
            app.NumberofPointsEditField.Limits = [0 Inf];
            app.NumberofPointsEditField.RoundFractionalValues = 'on';
            app.NumberofPointsEditField.Value = 100;
            app.XEditFieldLabel = uilabel(app.RightPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XDomainEditField = uieditfield(app.RightPanel, 'numeric');
            app.XDomainEditField.Limits = [0 Inf];
            app.XDomainEditField.RoundFractionalValues = 'on';
            app.XDomainEditField.Value = 1;
            app.XGridEditField = uieditfield(app.RightPanel, 'numeric');
            app.XGridEditField.Limits = [0 Inf];
            app.XGridEditField.RoundFractionalValues = 'on';
            app.XGridEditField.Value = 100;
            app.YEditFieldLabel = uilabel(app.RightPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YDomainEditField = uieditfield(app.RightPanel, 'numeric');
            app.YDomainEditField.Limits = [0 Inf];
            app.YDomainEditField.RoundFractionalValues = 'on';
            app.YDomainEditField.Value = 1;
            app.YGridEditField = uieditfield(app.RightPanel, 'numeric');
            app.YGridEditField.Limits = [0 Inf];
            app.YGridEditField.RoundFractionalValues = 'on';
            app.YGridEditField.Value = 100;
            app.ChordLengthEditFieldLabel = uilabel(app.RightPanel);
            app.ChordLengthEditFieldLabel.Text = 'Chord Length';
            app.ChordLengthEditField = uieditfield(app.RightPanel, 'numeric');
            app.ChordLengthEditField.Limits = [0 Inf];
            app.ChordLengthEditField.Value = 0.4;
            app.AngleofAttackEditFieldLabel = uilabel(app.RightPanel);
            app.AngleofAttackEditFieldLabel.Text = 'Angle of Attack';
            app.AngleofAttackEditField = uieditfield(app.RightPanel, 'numeric');
            app.AngleofAttackEditField.Limits = [-180 180];
            app.NACA4DigitCodeEditFieldLabel = uilabel(app.RightPanel);
            app.NACA4DigitCodeEditFieldLabel.Text = 'NACA 4 Digit Code';
            app.NACA4DigitCodeEditField = uieditfield(app.RightPanel, 'text');
            app.NACA4DigitCodeEditField.CharacterLimits = [4 4];
            app.NACA4DigitCodeEditField.Placeholder = 'MPXX';
            app.PositionofLeadingEdgexyLabel = uilabel(app.RightPanel);
            app.PositionofLeadingEdgexyLabel.Text = 'Grid Position of Leading Edge (x%,y%)';
            app.CoordinatesofLeadingEdgeX = uieditfield(app.RightPanel, 'numeric');
            app.CoordinatesofLeadingEdgeX.Limits = [0 100];
            app.CoordinatesofLeadingEdgeX.Value = 50;
            app.CoordinatesofLeadingEdgeY = uieditfield(app.RightPanel, 'numeric');
            app.CoordinatesofLeadingEdgeY.Limits = [0 100];
            app.CoordinatesofLeadingEdgeY.Value = 50;
            app.IterationLabel = uilabel(app.RightPanel);
            app.IterationLabel.Text = 'Iteration';
            app.IterationNumberField = uieditfield(app.RightPanel, 'numeric');
            app.IterationNumberField.Limits = [0 Inf];
            app.IterationNumberField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CFD_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end